classdef MRI_Parameters_Report < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label                          matlab.ui.control.Label
        RenamethefolderswithsequencenamePanel  matlab.ui.container.Panel
        RunButton                      matlab.ui.control.Button
        SortPath                       matlab.ui.control.EditField
        RawDataFolderLabel             matlab.ui.control.Label
        AddSort                        matlab.ui.control.Button
        ReportButton                   matlab.ui.control.Button
        MRIScanParameterReportLabel    matlab.ui.control.Label
        MRIParameterInformationPanel   matlab.ui.container.Panel
        MatrixEditFieldLabel_7         matlab.ui.control.Label
        FOVEditField_2                 matlab.ui.control.EditField
        TIEditFieldLabel_14            matlab.ui.control.Label
        TIEditField_7                  matlab.ui.control.EditField
        TIEditFieldLabel_13            matlab.ui.control.Label
        TREditFieldLabel_12            matlab.ui.control.Label
        FAEditField_6                  matlab.ui.control.EditField
        TREditFieldLabel_11            matlab.ui.control.Label
        text_gap_mm_EditFieldLabel     matlab.ui.control.Label
        SliceGapEditField_5            matlab.ui.control.EditField
        text_isgap_EditFieldLabel      matlab.ui.control.Label
        NumberofVolumesEditField       matlab.ui.control.EditField
        NumberofVolumesEditFieldLabel  matlab.ui.control.Label
        SliceNumberEditField           matlab.ui.control.EditField
        SliceNumberEditFieldLabel      matlab.ui.control.Label
        SliceOrderEditField            matlab.ui.control.EditField
        SliceOrderEditFieldLabel       matlab.ui.control.Label
        MatrixEditFieldLabel_6         matlab.ui.control.Label
        MatrixEditFieldLabel_5         matlab.ui.control.Label
        Voxel_Size3_EditField          matlab.ui.control.EditField
        MatrixEditFieldLabel_4         matlab.ui.control.Label
        Voxel_Size1_EditField          matlab.ui.control.EditField
        MatrixEditFieldLabel_3         matlab.ui.control.Label
        Voxel_Size2_EditField          matlab.ui.control.EditField
        MatrixEditFieldLabel_2         matlab.ui.control.Label
        MatrixEditField_2              matlab.ui.control.EditField
        MatrixEditField                matlab.ui.control.EditField
        MatrixEditFieldLabel           matlab.ui.control.Label
        TREditFieldLabel_8             matlab.ui.control.Label
        FOVEditField_1                 matlab.ui.control.EditField
        TREditFieldLabel_7             matlab.ui.control.Label
        TEEditFieldLabel_6             matlab.ui.control.Label
        TEEditField                    matlab.ui.control.EditField
        TREditFieldLabel_5             matlab.ui.control.Label
        SequenceNameEditField_2        matlab.ui.control.EditField
        SequenceNameEditField_2Label   matlab.ui.control.Label
        TREditFieldLabel_4             matlab.ui.control.Label
        SliceThicknessEditField        matlab.ui.control.EditField
        TREditFieldLabel_3             matlab.ui.control.Label
        TREditFieldLabel_2             matlab.ui.control.Label
        TREditField                    matlab.ui.control.EditField
        TREditFieldLabel               matlab.ui.control.Label
        MRISystemEditField             matlab.ui.control.EditField
        MRISystemEditFieldLabel        matlab.ui.control.Label
        AddPath                        matlab.ui.control.Button
        DcmPath                        matlab.ui.control.EditField
        RawDCMIMGFolderLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddPath
        function AddPathPushed(app, event)
            dcm_path=uigetdir(pwd);
            if ischar(dcm_path)
                app.DcmPath.Value=dcm_path;
                
                if ~isempty(app.MRISystemEditField.Value)
                    app.MRISystemEditField.Value='';
                    app.SequenceNameEditField_2.Value='';
                    
                    app.TREditField.Value='';
                    app.TEEditField.Value='';
                    app.FAEditField_6.Value='';
                    
                    app.TIEditField_7.Visible='on';
                    app.TIEditField_7.Value='';
                    app.TIEditFieldLabel_13.Visible="on";
                    app.TIEditFieldLabel_14.Visible="on";
                    
                    app.FOVEditField_1.Value='';
                    app.FOVEditField_2.Value='';
                    app.MatrixEditField.Value='';
                    app.MatrixEditField_2.Value='';
                    
                    app.NumberofVolumesEditField.Value='';
                    app.SliceNumberEditField.Value='';
                    
                    app.SliceThicknessEditField.Value ='';
                    app.text_isgap_EditFieldLabel.Visible="on";
                    app.SliceGapEditField_5.Visible="on";
                    app.SliceGapEditField_5.Value='';
                    app.text_gap_mm_EditFieldLabel.Visible="on";
                    
                    app.Voxel_Size1_EditField.Value='';
                    app.Voxel_Size2_EditField.Value='';
                    app.Voxel_Size3_EditField.Value='';
                                        
                    app.SliceOrderEditFieldLabel.Visible="on";
                    app.SliceOrderEditField.Visible="on";
                    app.SliceOrderEditField.Value='';
                end
                
            end
        end

        % Button pushed function: AddSort
        function AddSortButtonPushed(app, event)
            
            sort_path=uigetdir(pwd);
            if ischar(sort_path)
                app.SortPath.Value=sort_path;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if ischar(app.SortPath.Value)
               mri_series_sorting(app.SortPath.Value);
               msgbox('MRI Data Sorting Done!', 'Success','warn');
            end
        end

        % Button pushed function: ReportButton
        function ReportButtonPushed(app, event)
            [pm_name,pm_path]=uiputfile('parameter.mat','Save the parameter infor');
            if ischar(app.DcmPath.Value)
                h=waitbar(0,'Please wait ... ...');
                
                pm.dcm_path=app.DcmPath.Value;
                
                pm.dcm_files=spm_get('Files',pm.dcm_path,'*.dcm');
                if isempty(pm.dcm_files)
                    pm.dcm_files=spm_get('Files',pm.dcm_path,'*.IMA');
                end
                if isempty(pm.dcm_files)
                    pm.dcm_files=spm_get('Files',pm.dcm_path,'*.ima');
                end
                if isempty(pm.dcm_files)
                    pm.dcm_files=spm_get('Files',pm.dcm_path,'*.img');
                end
                
                
                waitbar(1/10,h);
                
                pm.hdr=spm_dicom_headers(pm.dcm_files(1,:));
                if isfield(pm.hdr{1},'ManufacturerModelName')
                    pm.MRI_System=[pm.hdr{1}.Manufacturer,pm.hdr{1}.ManufacturerModelName,pm.hdr{1}.SoftwareVersions];
                else
                    pm.MRI_System=[pm.hdr{1}.Manufacturer];
                end
                
                if isfield(pm.hdr{1},'SequenceName')
                    pm.series_name=pm.hdr{1}.SequenceName;
                else
                    pm.series_name=pm.hdr{1}.SeriesDescription;
                end
                
                pm.TR=pm.hdr{1}.RepetitionTime;
                pm.TE=pm.hdr{1}.EchoTime;
                pm.FA=pm.hdr{1}.FlipAngle;
                
                pm.slice_thickness=pm.hdr{1}.SliceThickness;
                
                waitbar(2/10,h);
                
                pm.PixelSpacing=pm.hdr{1}.PixelSpacing';
                if isfield(pm.hdr{1},'SpacingBetweenSlices')
                    pm.SpacingBetweenSlices=pm.hdr{1}.SpacingBetweenSlices;
                end
                
                
                % dcm2nii using dcm2niix ----------------------------------------------
                [ProgramPath, ~, ~] = fileparts(which('dcm2niix.exe'));
                
                if isempty(ProgramPath)
                    warndlg({'There is no dcm2nii.exe in your Matlab search path.'; []; 'Please add the Dpabi or other toolbox containing dcm2nii.exe to Matlab search path!!!'},'Warning');
                end

                cd(ProgramPath);
                InputDir = pm.dcm_path;
                OutputDir = fullfile(pm_path,'tmp1');
                mkdir(OutputDir);
            
                Option='-b y -x y -z n'; 
                
                if ispc
                    eval(['!dcm2niix.exe ',Option,' -o ',OutputDir,' ',InputDir]); 
                elseif ismac
                    eval(['!./dcm2niix_mac ',Option,' -o ',OutputDir,' ',InputDir]);
                else
                    eval(['!chmod +x dcm2niix_linux']);
                    eval(['!./dcm2niix_linux ',Option,' -o ',OutputDir,' ',InputDir]);  
                end
                
                cd(OutputDir);
                nii_pn = dir(fullfile(OutputDir,'*.nii'));
                jsn_pn = dir(fullfile(OutputDir,'*.json'));
                % dcm2nii using dcm2niix ----------------------------------------------
                
                
                waitbar(4/10,h);
                
                v = spm_vol(nii_pn(1).name);
                pm.dim = v(1,1).dim;
                pm.private_size = size(v(1,1).private.dat);                
                if length(pm.private_size) > 3  
                    
                    if strcmp(app.SliceGapEditField_5.Visible,'off')
                        app.text_gap_mm_EditFieldLabel.Visible = 'on';
                        app.text_isgap_EditFieldLabel.Visible = 'on';
                        app.SliceGapEditField_5.Visible = 'on';
                        app.SliceGapEditField_5.Value = '';
                    end
                    
                    if strcmp(app.SliceOrderEditField.Visible,'off')
                       app.SliceOrderEditFieldLabel.Visible = 'on';
                       app.SliceOrderEditField.Visible = 'on';
                       app.SliceOrderEditField.Value = '';
                    end
                    
                    jsn_fnm = jsn_pn(1).name;
                    pm.slice_times = jsonVal(jsn_fnm, '"SliceTiming":');
                    [~,pm.slice_order] = sort(pm.slice_times');
                    app.SliceOrderEditField.Value = num2str(pm.slice_order);
                    
                    if all(diff(pm.slice_order)>0) || all(diff(pm.slice_order)<0)
                        pm.inter_gap=pm.SpacingBetweenSlices;
                        app.SliceGapEditField_5.Value = num2str(pm.inter_gap);
                    else
                        pm.inter_gap=pm.SpacingBetweenSlices-pm.slice_thickness;
                        app.SliceGapEditField_5.Value = num2str(pm.inter_gap);
                    end
                    pm.slice_num=pm.dim(3);
                    pm.Matrix_nii=[pm.dim([1:2])];
                else
                    app.text_isgap_EditFieldLabel.Visible = 'off';
                    app.text_gap_mm_EditFieldLabel.Visible = 'off';
                    app.SliceGapEditField_5.Visible = 'off';
                    pm.inter_gap=0; 
                    
                    app.SliceOrderEditFieldLabel.Visible="off";
                    app.SliceOrderEditField.Visible="off";
                    pm.slice_num=size(pm.dcm_files,1);
                    pm.Matrix_nii=[pm.dim([2:3])];
                end
                cd(pm_path);
                rmdir(OutputDir,'s');
                
                if size(v,1) > 1
                    pm.volume_number = size(v,1);
                else
                    pm.volume_number = 1;   
                end
                
                                
                pm.FOV=pm.PixelSpacing.*pm.Matrix_nii;
                pm.Voxel_Size=[pm.PixelSpacing,pm.slice_thickness];
                
                waitbar(6/10,h);

                app.MRISystemEditField.Value=pm.MRI_System;
                app.SequenceNameEditField_2.Value=pm.series_name;  
                
                app.TREditField.Value=num2str(pm.TR);
                app.TEEditField.Value=num2str(pm.TE);
                app.FAEditField_6.Value=num2str(pm.FA);
                
                if isfield(pm.hdr{1},'InversionTime')
                    pm.TI=pm.hdr{1}.InversionTime;
                    app.TIEditField_7.Value=num2str(pm.TI);
                else
                    app.TIEditField_7.Visible='off';
                    app.TIEditFieldLabel_13.Visible="off";
                    app.TIEditFieldLabel_14.Visible="off";
                end
                
                waitbar(8/10,h);
                
                app.FOVEditField_1.Value=num2str(pm.FOV(1));
                app.FOVEditField_2.Value=num2str(pm.FOV(2));
                app.MatrixEditField.Value=num2str(pm.Matrix_nii(1));
                app.MatrixEditField_2.Value=num2str(pm.Matrix_nii(2));
                
                app.NumberofVolumesEditField.Value=num2str(pm.volume_number);
                app.SliceNumberEditField.Value=num2str(pm.slice_num);
                
                app.SliceThicknessEditField.Value =num2str(pm.slice_thickness);
                
                app.Voxel_Size1_EditField.Value=num2str(pm.PixelSpacing(1));
                app.Voxel_Size2_EditField.Value=num2str(pm.PixelSpacing(2));
                app.Voxel_Size3_EditField.Value=num2str(pm.slice_thickness);
                
                waitbar(10/10,h);
                delete(h);
                
                save(fullfile(pm_path,pm_name),'pm');
                
                msgbox('Operation Completed!','Success','help');
            else
                msgbox('Please select a T1/Fun Dicom folder!','Warning','warn');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RawDCMIMGFolderLabel = uilabel(app.UIFigure);
            app.RawDCMIMGFolderLabel.Text = 'Raw DCM/IMG Folder:';
            app.DcmPath = uieditfield(app.UIFigure, 'text');
            app.AddPath = uibutton(app.UIFigure, 'push');
            app.AddPath.ButtonPushedFcn = createCallbackFcn(app, @AddPathPushed, true);
            app.AddPath.Text = '...';
            app.MRIParameterInformationPanel = uipanel(app.UIFigure);
            app.MRIParameterInformationPanel.Title = 'MRI Parameter Information';
            app.MRISystemEditFieldLabel = uilabel(app.MRIParameterInformationPanel);
            app.MRISystemEditFieldLabel.Text = 'MRI System:';
            app.MRISystemEditField = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.TREditFieldLabel = uilabel(app.MRIParameterInformationPanel);
            app.TREditFieldLabel.Text = 'TR:';
            app.TREditField = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.TREditFieldLabel_2 = uilabel(app.MRIParameterInformationPanel);
            app.TREditFieldLabel_2.Text = 'ms';
            app.TREditFieldLabel_3 = uilabel(app.MRIParameterInformationPanel);
            app.TREditFieldLabel_3.Text = 'mm';
            app.SliceThicknessEditField = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.TREditFieldLabel_4 = uilabel(app.MRIParameterInformationPanel);
            app.TREditFieldLabel_4.Text = 'Slice Thickness:';
            app.SequenceNameEditField_2Label = uilabel(app.MRIParameterInformationPanel);
            app.SequenceNameEditField_2Label.Text = 'Sequence Name:';
            app.SequenceNameEditField_2 = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.TREditFieldLabel_5 = uilabel(app.MRIParameterInformationPanel);
            app.TREditFieldLabel_5.Text = 'ms';
            app.TEEditField = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.TEEditFieldLabel_6 = uilabel(app.MRIParameterInformationPanel);
            app.TEEditFieldLabel_6.Text = 'TE:';
            app.TREditFieldLabel_7 = uilabel(app.MRIParameterInformationPanel);
            app.TREditFieldLabel_7.Text = 'mm2';
            app.FOVEditField_1 = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.TREditFieldLabel_8 = uilabel(app.MRIParameterInformationPanel);
            app.TREditFieldLabel_8.Text = 'FOV:';
            app.MatrixEditFieldLabel = uilabel(app.MRIParameterInformationPanel);
            app.MatrixEditFieldLabel.Text = 'Matrix:';
            app.MatrixEditField = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.MatrixEditField_2 = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.MatrixEditFieldLabel_2 = uilabel(app.MRIParameterInformationPanel);
            app.MatrixEditFieldLabel_2.Text = 'x';
            app.Voxel_Size2_EditField = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.MatrixEditFieldLabel_3 = uilabel(app.MRIParameterInformationPanel);
            app.MatrixEditFieldLabel_3.Text = 'x';
            app.Voxel_Size1_EditField = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.MatrixEditFieldLabel_4 = uilabel(app.MRIParameterInformationPanel);
            app.MatrixEditFieldLabel_4.Text = 'Voxel Size:';
            app.Voxel_Size3_EditField = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.MatrixEditFieldLabel_5 = uilabel(app.MRIParameterInformationPanel);
            app.MatrixEditFieldLabel_5.Text = 'x';
            app.MatrixEditFieldLabel_6 = uilabel(app.MRIParameterInformationPanel);
            app.MatrixEditFieldLabel_6.Text = 'mm3';
            app.SliceOrderEditFieldLabel = uilabel(app.MRIParameterInformationPanel);
            app.SliceOrderEditFieldLabel.Text = 'Slice Order:';
            app.SliceOrderEditField = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.SliceNumberEditFieldLabel = uilabel(app.MRIParameterInformationPanel);
            app.SliceNumberEditFieldLabel.Text = 'Slice Number:';
            app.SliceNumberEditField = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.NumberofVolumesEditFieldLabel = uilabel(app.MRIParameterInformationPanel);
            app.NumberofVolumesEditFieldLabel.Text = 'Number of Volume(s):';
            app.NumberofVolumesEditField = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.text_isgap_EditFieldLabel = uilabel(app.MRIParameterInformationPanel);
            app.text_isgap_EditFieldLabel.Text = 'Inter-Slice Gap:';
            app.SliceGapEditField_5 = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.text_gap_mm_EditFieldLabel = uilabel(app.MRIParameterInformationPanel);
            app.text_gap_mm_EditFieldLabel.Text = 'mm';
            app.TREditFieldLabel_11 = uilabel(app.MRIParameterInformationPanel);
            app.TREditFieldLabel_11.Text = '°';
            app.FAEditField_6 = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.TREditFieldLabel_12 = uilabel(app.MRIParameterInformationPanel);
            app.TREditFieldLabel_12.Text = 'FA:';
            app.TIEditFieldLabel_13 = uilabel(app.MRIParameterInformationPanel);
            app.TIEditFieldLabel_13.Text = 'ms';
            app.TIEditField_7 = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.TIEditFieldLabel_14 = uilabel(app.MRIParameterInformationPanel);
            app.TIEditFieldLabel_14.Text = 'TI:';
            app.FOVEditField_2 = uieditfield(app.MRIParameterInformationPanel, 'text');
            app.MatrixEditFieldLabel_7 = uilabel(app.MRIParameterInformationPanel);
            app.MatrixEditFieldLabel_7.Text = 'x';
            app.MRIScanParameterReportLabel = uilabel(app.UIFigure);
            app.MRIScanParameterReportLabel.Text = 'MRI Scan Parameter Report';
            app.ReportButton = uibutton(app.UIFigure, 'push');
            app.ReportButton.ButtonPushedFcn = createCallbackFcn(app, @ReportButtonPushed, true);
            app.ReportButton.Text = 'Report';
            app.RenamethefolderswithsequencenamePanel = uipanel(app.UIFigure);
            app.RenamethefolderswithsequencenamePanel.Title = 'Rename the folders with sequence name';
            app.AddSort = uibutton(app.RenamethefolderswithsequencenamePanel, 'push');
            app.AddSort.ButtonPushedFcn = createCallbackFcn(app, @AddSortButtonPushed, true);
            app.AddSort.Text = '...';
            app.RawDataFolderLabel = uilabel(app.RenamethefolderswithsequencenamePanel);
            app.RawDataFolderLabel.Text = 'Raw Data Folder:';
            app.SortPath = uieditfield(app.RenamethefolderswithsequencenamePanel, 'text');
            app.RunButton = uibutton(app.RenamethefolderswithsequencenamePanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Hou Xin'; 'houxin195776@mail.bnu.edu.cn'; 'Beijing Normal University Beijing, China'; 'State Key Laboratory of Cognitive Neuroscience and Learning'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRI_Parameters_Report

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end